classdef cellapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EditField_9               matlab.ui.control.NumericEditField
        Label_20                  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        Label_19                  matlab.ui.control.Label
        Label_18                  matlab.ui.control.Label
        Label_17                  matlab.ui.control.Label
        Label_16                  matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        Label_14                  matlab.ui.control.Label
        EditField_8               matlab.ui.control.NumericEditField
        Slider_8                  matlab.ui.control.RangeSlider
        Label_13                  matlab.ui.control.Label
        Label_11                  matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Switch                    matlab.ui.control.Switch
        Label_2                   matlab.ui.control.Label
        EditField_7               matlab.ui.control.NumericEditField
        LevelEditFieldLabel_8     matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        LevelEditField_2          matlab.ui.control.NumericEditField
        Slider_6                  matlab.ui.control.Slider
        EditField_6               matlab.ui.control.NumericEditField
        EditField_5               matlab.ui.control.NumericEditField
        Slider_5                  matlab.ui.control.Slider
        LevelEditFieldLabel_7     matlab.ui.control.Label
        LevelEditFieldLabel_6     matlab.ui.control.Label
        TrackingLabel_2           matlab.ui.control.Label
        Switch_2                  matlab.ui.control.Switch
        EditField_4               matlab.ui.control.NumericEditField
        LevelEditFieldLabel_5     matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        LevelEditFieldLabel_4     matlab.ui.control.Label
        LevelEditFieldLabel_3     matlab.ui.control.Label
        Slider_4                  matlab.ui.control.Slider
        Slider_3                  matlab.ui.control.Slider
        DropDown                  matlab.ui.control.DropDown
        SegmentationNetworkLabel  matlab.ui.control.Label
        LevelEditFieldLabel_2     matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        LevelEditField            matlab.ui.control.NumericEditField
        LevelEditFieldLabel       matlab.ui.control.Label
        PreProcessingLabel        matlab.ui.control.Label
        AdvancedLabel             matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        Slider                    matlab.ui.control.Slider
        ConfidenceThresholdLabel  matlab.ui.control.Label
        RelaxedButton             matlab.ui.control.Button
        ConservativeButton        matlab.ui.control.Button
        DefaultButton             matlab.ui.control.Button
        PresetLabel               matlab.ui.control.Label
        SettingsLabel             matlab.ui.control.Label
        InitialLabel              matlab.ui.control.Label
        YFeatureLabel_2           matlab.ui.control.Label
        XFeatureLabel_2           matlab.ui.control.Label
        PauseButton               matlab.ui.control.Button
        Label_21                  matlab.ui.control.Label
        Label_22                  matlab.ui.control.Label
        PlotFeaturesText          matlab.ui.control.Label
        NextButton                matlab.ui.control.Button
        PreviousButton            matlab.ui.control.Button
        ModeSwitch                matlab.ui.control.Switch
        ImageDisp                 matlab.ui.control.Image
        ExportData_Button         matlab.ui.control.Button
        Credit                    matlab.ui.control.Hyperlink
        XDropDown                 matlab.ui.control.DropDown
        YDropDown                 matlab.ui.control.DropDown
        Number_of_Cells_Field     matlab.ui.control.NumericEditField
        Number_of_Cells_Text      matlab.ui.control.Label
        Segment_Button            matlab.ui.control.Button
        Help_Button               matlab.ui.control.Button
        Settings_Button           matlab.ui.control.Button
        UploadImagesButton        matlab.ui.control.Button
        ProgressBarAxes           matlab.ui.control.UIAxes
        HistogramAxes             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images cell = {}; % Stores the uploaded image
    CurrentImageIndex double = 1;
    SegmentedImages = []; % Stores the segmented image
    net = []; % Stores the pre-trained CNN network
    Masks cell = {};
    Boxes cell = {};
    Labels cell = {};
    Scores cell = {};
    AnalysisData = [];
        Progress = 0; % Description
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")

        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end
       
        function updateDisplayedImage(app)
            if isempty(app.SegmentedImages)
                return;
            end
    
            fixIMGDisplay(app, app.SegmentedImages{app.CurrentImageIndex}, app.ImageDisp);
            data = app.AnalysisData{app.CurrentImageIndex};
            app.Number_of_Cells_Field.Value = size(data, 1);
            app.Area_Field.Value = mean(data.Area);
            app.updatePlot();
        end

       
        function updatePlot(app)
       % Plotting depending on toggles
            if isempty(app.statsPerImage)
                return;
            end

            % Gather stats
            if  strcmp(app.ToggleCumulative.Value, 'On')
                % Cumulative across all images
                combinedStats = vertcat(app.statsPerImage{:});
            else
                % Just show first image's stats
                combinedStats = app.statsPerImage{1};
            end

            % Decide plot type
            if  strcmp(app.YDropDown.Value, 'Frequency')
                % Histogram view
                featureNameX = app.XDropDown.Value;
                featureValues = [combinedStats.(featureName)];
                histogram(app.HistogramAxes, featureValues);
            else
                % Scatter plot view
                featureX = app.XDropDown.Value;
                featureY = app.YDropDown.Value;

                xValues = [combinedStats.(featureX)];
                yValues = [combinedStats.(featureY)];

                scatter(app.HistogramAxes, xValues, yValues, 'filled');
            end
                xlabel(app.HistogramAxes, featureX);
                ylabel(app.HistogramAxes, featureY);
        end

      
        function UpdateProgress(app)
            barh(app.Progress, 'Parent',app.ProgressBarAxes,'EdgeColor','none','FaceColor',[0.4660 0.6740 0.1880], "BarWidth" ,23000)
            %%%%%%%%% progress bar update!!!!!! text(x, y,
            %%%%%%%%% num2str(app.Progress*100, 0 decimals)+"%",...) 
            drawnow;
        end
    
        function highlightButton(app, selectedButton)
    allButtons = [app.DefaultButton, app.ConservativeButton, app.RelaxedButton];

    for btn = allButtons
        btn.BackgroundColor = [0.76 0.80 0.80];  
        btn.FontColor = [0 0 0];                
    end

    selectedButton.BackgroundColor = [0.05 0.30 0.30];  % Blue highlight
    selectedButton.FontColor = [1 1 1];                % White text
end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
            addpath(genpath('MRCNNsrc'));

            app.net = load('net'); % Load the network file
            app.net = app.net.net; % Extract the network object
            set(app.ProgressBarAxes,'visible','off');
            set(app.ProgressBarAxes,'xtick',[]);
            UpdateProgress(app);
            set(app.ProgressBarAxes, 'XLim', [0 1]);
        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            app.InitialLabel.Visible = 'off';
            
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
           % if isequal(files, 0)
           %     errordlg('No images selected!', 'Error');
           %     return;
           % end

            if ~iscell(files)
                files = {files};
            end

            app.Images = {};
            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));
                [img, ~] = resizeImageandMask(img, [], [528, 704]);

                if size(img, 3) == 3
                    img = mean(img, 3);
                end
                app.Images{end+1} = img;
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            fixIMGDisplay(app, app.Images{1}, app.ImageDisp);
            
            app.Segment_Button.Visible='on';


        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end 
        
        app.Segment_Button.Visible='off';
        app.HistogramAxes.Visible = 'on';
        app.Number_of_Cells_Field.Visible = 'on';
        app.Number_of_Cells_Text.Visible = 'on';
        app.PlotFeaturesText.Visible = 'on';
        app.ModeSwitch.Visible = 'on';
        app.NextButton.Visible = 'on';
        app.PreviousButton.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.PauseButton.Visible = 'on';
        app.XFeatureLabel_2.Visible = 'on';
        app.YFeatureLabel_2.Visible = 'on';
        app.Label_20.Visible = 'on';
        
    % Add MRCNNsrc path (Make sure this path is correct)

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end


    % Perform cell segmentation
    try
        if ~class(app.Images)=='cell'
            app.Images{1} = app.Images; %makes sure all images have same format: cell, containing each image
            % move this logic to images being loaded later 
        end

        for i=[1:size(app.Images,2)] %for each image loaded in
        
         app.Progress = ((i-1)+ 0.05) / size(app.Images,2);
         UpdateProgress(app);
        
        [app.Masks{i} app.Labels{i} app.Scores{i} app.Boxes{i}] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",1000, MinSize=[4 4], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=0.5);

         app.Progress = ((i-1)+ 0.9) / size(app.Images,2);
         UpdateProgress(app);
         app.Label_20.Visible = 'off';
         app.Label_22.Visible = 'on';
            
        if isempty(app.Masks{i})
            app.SegmentedImages = app.Images{i};
        else
            app.SegmentedImages = insertObjectMask(app.Images{i}, app.Masks{i}, Color=lines(size(app.Masks{i}, 3)) );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
        drawnow
        app.AnalysisData{i} = ExtractFeatures(app.Images{i}, app.Masks{i}, app.Boxes{i}, app.Scores{i}, 1);
        app.Label_22.Visible = 'off';
        app.Label_21.Visible = 'on';
        
       % app.AnalysisData{1}.Area

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData{i}, 1);
        %app.Area_Field.Value = mean(app.AnalysisData.Area);
        app.Progress = i / size(app.Images,2);
        UpdateProgress(app);
       
        
        end
        app.PauseButton.Visible = 'off';
        app.ExportData_Button.Visible = 'on';
      
    
    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end


        end

        % Value changed function: XDropDown, YDropDown
        function PlotHistogram(app, event)
            xFeature = app.XDropDown.Value;
            yFeature = app.YDropDown.Value;

            if isempty(app.AnalysisData)
                return;
            end

            % Extract the selected feature's values
            featureData = app.AnalysisData{1};

            % Decide plot type
            if  strcmp(yFeature, 'Frequency')
                
                % Plot histogram
                histogram(app.HistogramAxes,featureData.(xFeature));
                xlabel(app.HistogramAxes, xFeature);
                ylabel(app.HistogramAxes, 'Frequency');
            else
                % Scatter plot view
                xValues = featureData.(xFeature);
                yValues = featureData.(yFeature);

        % Create scatter plot
        scatter(app.HistogramAxes, xValues, yValues, 'filled');
        xlabel(app.HistogramAxes, xFeature);
        ylabel(app.HistogramAxes, yFeature);


            end
        end

        % Callback function
        function PreviousButtonPushed(app, event)
              if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                app.updateDisplayedImage();
              end
        
        end

        % Callback function
        function NextButtonPushed(app, event)
           if app.CurrentImageIndex < length(app.SegmentedImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                app.updateDisplayedImage();
           end
        
        end

        % Button pushed function: PreviousButton
        function ToggleSwitchValueChanged(app, event)

        end

        % Callback function
        function XAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function YAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Button pushed function: Settings_Button
        function Settings_ButtonPushed(app, event)

    if strcmp(app.Panel.Visible, 'on')
        app.Panel.Visible = 'off';
    else
        app.Panel.Visible = 'on';
    end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Panel.Visible = 'off';

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            if strcmp(app.Switch_2.Value, 'Off')
                app.LevelEditField.Visible = "off";
                app.LevelEditField_2.Visible = "off";
                app.LevelEditFieldLabel.Visible = "off";
                app.LevelEditFieldLabel_2.Visible = "off";
                app.Slider_2.Visible = 'off';
                app.Label_19.Visible = 'off';
            else
                app.LevelEditField.Visible = "on";
                app.LevelEditField_2.Visible = "on";
                app.LevelEditFieldLabel.Visible = "on";
                app.LevelEditFieldLabel_2.Visible = "on";
                app.Slider_2.Visible = 'on';
                app.Label_19.Visible = 'on';

            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
         
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
             if strcmp(app.Switch.Value, 'Off')
                app.Slider_6.Visible = 'off';
                app.EditField_6.Visible = "off";
                app.EditField_5.Visible = 'off';
                app.Slider_5.Visible = 'off';
                app.LevelEditFieldLabel_6.Visible = 'off';
                app.LevelEditFieldLabel_7.Visible = 'off';
                app.Label_11.Visible = 'off';
                app.Label_13.Visible = 'off';
            else
                app.Slider_6.Visible = 'on';
                app.EditField_6.Visible = "on";
                app.EditField_5.Visible = 'on';
                app.Slider_5.Visible = 'on';
                app.LevelEditFieldLabel_6.Visible = 'on';
                app.LevelEditFieldLabel_7.Visible = 'on';
                app.Label_11.Visible = 'on';
                app.Label_13.Visible = 'on';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)

        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
       highlightButton(app, app.DefaultButton);
        end

        % Button pushed function: ConservativeButton
        function ConservativeButtonPushed(app, event)
        highlightButton(app, app.ConservativeButton);
        end

        % Button pushed function: RelaxedButton
        function RelaxedButtonPushed(app, event)
       highlightButton(app, app.RelaxedButton);
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            
    if strcmp(value, 'All')
        % Show the Previous and Next buttons
        app.PreviousButton.Visible = 'on';
        app.NextButton.Visible = 'on';
    else
        % Hide the Previous and Next buttons
        app.PreviousButton.Visible = 'off';
        app.NextButton.Visible = 'off';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            app.HistogramAxes.Visible = 'off';
            colormap(app.HistogramAxes, 'turbo')
            app.ProgressBarAxes = uiaxes(app.UIFigure);
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Images';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.ButtonPushedFcn = createCallbackFcn(app, @Settings_ButtonPushed, true);
            app.Settings_Button.Icon = fullfile(pathToMLAPP, 'download.png');
            app.Settings_Button.Tooltip = {'Settings'};
            app.Settings_Button.Text = '';
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Tooltip = {'Help'};
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Visible = 'off';
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Visible = 'off';
            app.Number_of_Cells_Text.Text = 'Number of Cells ';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Cells_Field.Limits = [0 Inf];
            app.Number_of_Cells_Field.RoundFractionalValues = 'on';
            app.Number_of_Cells_Field.AllowEmpty = 'on';
            app.Number_of_Cells_Field.Editable = 'off';
            app.Number_of_Cells_Field.Visible = 'off';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Frequency', 'Area', 'Perimeter', 'Aspect Ratio', 'Mean Intensity', 'STDeviation', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.YDropDown.Visible = 'off';
            app.YDropDown.Value = 'Area';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Area', 'Perimeter', 'Aspect Ratio', 'Mean Intensity', 'STDeviation', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.XDropDown.Visible = 'off';
            app.XDropDown.Value = 'Area';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = {'GitHub'; ''};
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Icon = fullfile(pathToMLAPP, 'export-icon-2048x2048-g2mr2v94.png');
            app.ExportData_Button.Visible = 'off';
            app.ExportData_Button.Tooltip = {'Export Data'};
            app.ExportData_Button.Text = '';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'All', 'Individual'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Visible = 'off';
            app.ModeSwitch.Value = 'All';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged, true);
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'Picture 2.png');
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Tooltip = {'Previous Image'};
            app.PreviousButton.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Icon = fullfile(pathToMLAPP, 'Picture 1.png');
            app.NextButton.Visible = 'off';
            app.NextButton.Tooltip = {'Next Image'};
            app.NextButton.Text = '';
            app.PlotFeaturesText = uilabel(app.UIFigure);
            app.PlotFeaturesText.Visible = 'off';
            app.PlotFeaturesText.Text = 'Plot Features:';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Visible = 'off';
            app.Label_22.Text = '80%';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Visible = 'off';
            app.Label_21.Text = '100%';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'Pause.svg');
            app.PauseButton.Visible = 'off';
            app.PauseButton.Tooltip = {'Pause'};
            app.PauseButton.Text = '';
            app.XFeatureLabel_2 = uilabel(app.UIFigure);
            app.XFeatureLabel_2.Visible = 'off';
            app.XFeatureLabel_2.Text = 'X ';
            app.YFeatureLabel_2 = uilabel(app.UIFigure);
            app.YFeatureLabel_2.Visible = 'off';
            app.YFeatureLabel_2.Text = 'Y';
            app.InitialLabel = uilabel(app.UIFigure);
            app.InitialLabel.Text = 'Please select images to start.';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.SettingsLabel = uilabel(app.Panel);
            app.SettingsLabel.Text = 'Settings';
            app.PresetLabel = uilabel(app.Panel);
            app.PresetLabel.Text = 'Preset';
            app.DefaultButton = uibutton(app.Panel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ConservativeButton = uibutton(app.Panel, 'push');
            app.ConservativeButton.ButtonPushedFcn = createCallbackFcn(app, @ConservativeButtonPushed, true);
            app.ConservativeButton.Text = 'Conservative';
            app.RelaxedButton = uibutton(app.Panel, 'push');
            app.RelaxedButton.ButtonPushedFcn = createCallbackFcn(app, @RelaxedButtonPushed, true);
            app.RelaxedButton.Text = 'Relaxed';
            app.ConfidenceThresholdLabel = uilabel(app.Panel);
            app.ConfidenceThresholdLabel.Text = 'Confidence Threshold';
            app.Slider = uislider(app.Panel);
            app.Slider.MajorTicks = [0 100];
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueDisplayFormat = '%5.2f';
            app.EditField.Placeholder = '20%';
            app.EditField.Value = 50;
            app.AdvancedLabel = uilabel(app.Panel);
            app.AdvancedLabel.Text = 'Advanced ';
            app.PreProcessingLabel = uilabel(app.Panel);
            app.PreProcessingLabel.Text = 'Pre-Processing';
            app.LevelEditFieldLabel = uilabel(app.Panel);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.Panel, 'numeric');
            app.LevelEditField.Value = 4;
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.Limits = [0 5];
            app.Slider_2.MajorTicks = [0 5];
            app.LevelEditFieldLabel_2 = uilabel(app.Panel);
            app.LevelEditFieldLabel_2.Text = 'Threshold';
            app.SegmentationNetworkLabel = uilabel(app.Panel);
            app.SegmentationNetworkLabel.Text = 'Segmentation Network';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'EfficientNet', 'ResNet50', 'ResNet101'};
            app.DropDown.Value = 'EfficientNet';
            app.Slider_3 = uislider(app.Panel);
            app.Slider_3.MajorTicks = [0 100];
            app.Slider_4 = uislider(app.Panel);
            app.Slider_4.MajorTicks = [0 100];
            app.LevelEditFieldLabel_3 = uilabel(app.Panel);
            app.LevelEditFieldLabel_3.Text = 'Overlap';
            app.LevelEditFieldLabel_4 = uilabel(app.Panel);
            app.LevelEditFieldLabel_4.Text = {'Proposal'; 'Overlap'};
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Value = 30;
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Value = 30;
            app.LevelEditFieldLabel_5 = uilabel(app.Panel);
            app.LevelEditFieldLabel_5.Text = 'Maximum Proposals';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.Value = 5000;
            app.Switch_2 = uiswitch(app.Panel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.TrackingLabel_2 = uilabel(app.Panel);
            app.TrackingLabel_2.Text = 'Tracking';
            app.LevelEditFieldLabel_6 = uilabel(app.Panel);
            app.LevelEditFieldLabel_6.Text = {'Min IoU '; 'Threshold'};
            app.LevelEditFieldLabel_7 = uilabel(app.Panel);
            app.LevelEditFieldLabel_7.Text = {'RPN '; 'Optimization '; 'Factor'};
            app.Slider_5 = uislider(app.Panel);
            app.Slider_5.Limits = [0 20];
            app.Slider_5.MajorTicks = [0 20];
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_5.Value = 15;
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.EditField_6.Value = 5;
            app.Slider_6 = uislider(app.Panel);
            app.Slider_6.Limits = [0 20];
            app.Slider_6.MajorTicks = [0 20];
            app.LevelEditField_2 = uieditfield(app.Panel, 'numeric');
            app.LevelEditField_2.Value = 2;
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'close.png');
            app.Button.Text = '';
            app.LevelEditFieldLabel_8 = uilabel(app.Panel);
            app.LevelEditFieldLabel_8.Text = 'Object Size';
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.EditField_7.Value = 4;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '________________________________________________________________________________________________________________________';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '____________________________________________________________________________________________________';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '____________________________________________________________________________________________________';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '%';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '%';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '%';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '%';
            app.Slider_8 = uislider(app.Panel, 'range');
            app.Slider_8.Limits = [0 250];
            app.Slider_8.MajorTicks = [0 250];
            app.Slider_8.Value = [0 250];
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.EditField_8.Value = 160;
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '________________________________________________________________________________________________________________________';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = '________________________________________________________________________________________________________________________';
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = '________________________________________________________________________________________________________________________';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = '________________________________________________________________________________________________________________________';
            app.Label_18 = uilabel(app.Panel);
            app.Label_18.Text = '________________________________________________________________________________________________________________________';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = '%';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Visible = 'off';
            app.Label_20.Text = '5%';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end